classdef proyecto_calculadora < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        CrdtiosButton      matlab.ui.control.Button
        Coeficiente        matlab.ui.control.EditField
        R2EditFieldLabel   matlab.ui.control.Label
        EcuacionRegresion  matlab.ui.control.EditField
        EcuacionLabel      matlab.ui.control.Label
        UITable            matlab.ui.control.Table
        TabladedatosLabel  matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        flag=1;
        datosTabla;
        coeficiente;
        ecuacion;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            tabladatos = readtable('Datos.xlsx',Sheet='Datos');
            app.UITable.Data = tabladatos;
            app.datosTabla = tabladatos;
            X=tabladatos.x;
            Y=tabladatos.y;
            %dispersion de puntos
            %regresion lineal
            coefs = polyfit(X, Y, 1);
            pendiente = coefs(1);
            intercepto = coefs(2);

            % Graficar puntos de datos y línea de regresión en la misma gráfica
            hold(app.UIAxes, 'on');  % Mantener el modo de espera en el objeto de gráfico existente

            % Graficar puntos de datos
            scatter(app.UIAxes, X, Y, 'o', 'filled', 'MarkerFaceColor', 'b');

            % Calcular valores predichos
            y_pred = pendiente * X + intercepto;
        
            % Graficar línea de regresión
            plot(app.UIAxes, X, y_pred, 'r', 'LineWidth', 2);

            % Personalizar etiquetas y título
            xlabel(app.UIAxes, 'Eje X');
            ylabel(app.UIAxes, 'Eje Y');
            title(app.UIAxes, 'Regresión Lineal');

            % Calcular el coeficiente de correlación (r^2)
            corr_matrix = corrcoef(X, Y);
            r_squared = corr_matrix(1, 2)^2;

            % Quitar el modo de espera
            hold(app.UIAxes, 'off');

            % Actualizar la caja de texto con la ecuación de la regresión lineal y r^2
            app.ecuacion = sprintf('y = %.4f * x + %.4f', pendiente, intercepto);
            app.coeficiente = sprintf('R^2 = %.4f',r_squared);
            app.EcuacionRegresion.Value = app.ecuacion;
            app.Coeficiente.Value = app.coeficiente;
        end

        % Button pushed function: CrdtiosButton
        function CrdtiosButtonPushed(app, event)
            Creditos;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Regresion')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'prism')
            app.TabladedatosLabel = uilabel(app.UIFigure);
            app.TabladedatosLabel.Text = 'Tabla de datos';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.EcuacionLabel = uilabel(app.UIFigure);
            app.EcuacionLabel.Text = 'Ecuacion';
            app.EcuacionRegresion = uieditfield(app.UIFigure, 'text');
            app.EcuacionRegresion.Editable = 'off';
            app.R2EditFieldLabel = uilabel(app.UIFigure);
            app.R2EditFieldLabel.Text = 'R^2';
            app.Coeficiente = uieditfield(app.UIFigure, 'text');
            app.Coeficiente.Editable = 'off';
            app.CrdtiosButton = uibutton(app.UIFigure, 'push');
            app.CrdtiosButton.ButtonPushedFcn = createCallbackFcn(app, @CrdtiosButtonPushed, true);
            app.CrdtiosButton.Text = 'Crédtios';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyecto_calculadora

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end